classdef GearStength2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CoursedesignLabel               matlab.ui.control.Label
        TabGroup6                       matlab.ui.container.TabGroup
        GearDesignTab                   matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        WorkingConditionsTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        InputPanel                      matlab.ui.container.Panel
        KWLabel                         matlab.ui.control.Label
        rpmLabel                        matlab.ui.control.Label
        hLabel                          matlab.ui.control.Label
        MeshingmethodDropDownLabel      matlab.ui.control.Label
        MeshingmethodDropDown           matlab.ui.control.DropDown
        SupportingEditFieldLabel        matlab.ui.control.Label
        SupportingEditField             matlab.ui.control.EditField
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        PowertransmittedPEditField_2Label  matlab.ui.control.Label
        PowertransmittedPEditField_2    matlab.ui.control.NumericEditField
        SpeedratioEditField_2Label      matlab.ui.control.Label
        SpeedratioEditField_2           matlab.ui.control.NumericEditField
        Rotationalspeedofthepinionn1EditField_2Label  matlab.ui.control.Label
        Rotationalspeedofthepinionn1EditField_2  matlab.ui.control.NumericEditField
        ServiceLifehEditField_2Label    matlab.ui.control.Label
        ServiceLifehEditField_2         matlab.ui.control.NumericEditField
        CallbackPanel                   matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        NmmLabel                        matlab.ui.control.Label
        TorqueT1EditField_2Label        matlab.ui.control.Label
        TorqueT1EditField_2             matlab.ui.control.NumericEditField
        AllowTab                        matlab.ui.container.Tab
        GearPanel                       matlab.ui.container.Panel
        AllowancecontactstressPanel_2   matlab.ui.container.Panel
        MpaLabel_3                      matlab.ui.control.Label
        MpaLabel_4                      matlab.ui.control.Label
        ContactendurancelimitHlim2EditFieldLabel  matlab.ui.control.Label
        ContactendurancelimitHlim2EditField  matlab.ui.control.NumericEditField
        LifefactorKHn2EditFieldLabel    matlab.ui.control.Label
        LifefactorKHn2EditField         matlab.ui.control.NumericEditField
        AllowablecontactstrengthH2EditFieldLabel  matlab.ui.control.Label
        AllowablecontactstrengthH2EditField  matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        InputLabel_3                    matlab.ui.control.Label
        OutputLabel_3                   matlab.ui.control.Label
        AllowancebendingstressPanel_2   matlab.ui.container.Panel
        MpaLabel_7                      matlab.ui.control.Label
        MpaLabel_8                      matlab.ui.control.Label
        BendingendurancelimitFlim2EditFieldLabel  matlab.ui.control.Label
        BendingendurancelimitFlim2EditField  matlab.ui.control.NumericEditField
        LifefactorKFn2EditFieldLabel    matlab.ui.control.Label
        LifefactorKFn2EditField         matlab.ui.control.NumericEditField
        AllowablecontactstrengthF2EditFieldLabel  matlab.ui.control.Label
        AllowablecontactstrengthF2EditField  matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        InputLabel_4                    matlab.ui.control.Label
        OutputLabel_4                   matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        PinionPanel                     matlab.ui.container.Panel
        AllowancecontactstressPanel     matlab.ui.container.Panel
        MpaLabel                        matlab.ui.control.Label
        MpaLabel_2                      matlab.ui.control.Label
        ContactendurancelimitHlim1EditField_2Label  matlab.ui.control.Label
        ContactendurancelimitHlim1EditField_2  matlab.ui.control.NumericEditField
        LifefactorKHn1EditFieldLabel    matlab.ui.control.Label
        LifefactorKHn1EditField         matlab.ui.control.NumericEditField
        AllowablecontactstrengthH1Label  matlab.ui.control.Label
        AllowablecontactstrengthH1EditField_2  matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        OutputLabel                     matlab.ui.control.Label
        AllowancebendingstressPanel     matlab.ui.container.Panel
        MpaLabel_5                      matlab.ui.control.Label
        MpaLabel_6                      matlab.ui.control.Label
        BendingendurancelimitFlim1EditFieldLabel  matlab.ui.control.Label
        BendingendurancelimitFlim1EditField  matlab.ui.control.NumericEditField
        LifefactorKFn1EditFieldLabel    matlab.ui.control.Label
        LifefactorKFn1EditField         matlab.ui.control.NumericEditField
        AllowablecontactstrengthF1EditFieldLabel  matlab.ui.control.Label
        AllowablecontactstrengthF1EditField  matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        InputLabel_2                    matlab.ui.control.Label
        OutputLabel_2                   matlab.ui.control.Label
        Numberofcyclesn1EditField_3Label  matlab.ui.control.Label
        Numberofcyclesn1EditField_3     matlab.ui.control.NumericEditField
        Numberofcyclesn2EditFieldLabel  matlab.ui.control.Label
        Numberofcyclesn2EditField       matlab.ui.control.NumericEditField
        LoadTab                         matlab.ui.container.Tab
        ApplicationfactorKAPanel        matlab.ui.container.Panel
        PrimemachineDropDownLabel       matlab.ui.control.Label
        PrimemachineDropDown            matlab.ui.control.DropDown
        WorkingmachineDropDown_2Label   matlab.ui.control.Label
        WorkingmachineDropDown_2        matlab.ui.control.DropDown
        TextArea_3                      matlab.ui.control.TextArea
        KAEditField_2Label              matlab.ui.control.Label
        KAEditField_2                   matlab.ui.control.NumericEditField
        DynamicfactorKvPanel            matlab.ui.container.Panel
        TextArea_4                      matlab.ui.control.TextArea
        KvEditField_2Label              matlab.ui.control.Label
        KvEditField_2                   matlab.ui.control.NumericEditField
        LoaddistributionfactorintoothdirectionKPanel  matlab.ui.container.Panel
        TextArea2_2                     matlab.ui.control.TextArea
        KEditField_3Label               matlab.ui.control.Label
        KEditField_3                    matlab.ui.control.NumericEditField
        CalculateButton_3               matlab.ui.control.Button
        Panel7                          matlab.ui.container.Panel
        TextArea2                       matlab.ui.control.TextArea
        KaEditField_2Label              matlab.ui.control.Label
        KaEditField_2                   matlab.ui.control.NumericEditField
        LoadfactorKEditField_2Label     matlab.ui.control.Label
        LoadfactorKEditField_2          matlab.ui.control.NumericEditField
        ParametersTab                   matlab.ui.container.Tab
        HeattreatmentPanel              matlab.ui.container.Panel
        HeattreatmentEditFieldLabel     matlab.ui.control.Label
        HeattreatmentEditField          matlab.ui.control.EditField
        PinionmaterialDropDownLabel     matlab.ui.control.Label
        PinionmaterialDropDown          matlab.ui.control.DropDown
        GearmaterialEditFieldLabel      matlab.ui.control.Label
        GearmaterialEditField           matlab.ui.control.EditField
        HeattreatmentEditField_2Label   matlab.ui.control.Label
        HeattreatmentEditField_2        matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup3                       matlab.ui.container.TabGroup
        PinionTab                       matlab.ui.container.Tab
        mmLabel_2                       matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        Label_5                         matlab.ui.control.Label
        ToothwidthratiodLabel           matlab.ui.control.Label
        Numofteethz1EditField_3Label    matlab.ui.control.Label
        Numofteethz1EditField_3         matlab.ui.control.NumericEditField
        Diameterd1EditField_3Label      matlab.ui.control.Label
        Diameterd1EditField_3           matlab.ui.control.NumericEditField
        HelixangleEditField_3Label      matlab.ui.control.Label
        HelixangleEditField_3           matlab.ui.control.NumericEditField
        PressureangleEditField_3Label   matlab.ui.control.Label
        PressureangleEditField_3        matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        GearTab                         matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        Numofteethz2EditFieldLabel      matlab.ui.control.Label
        Numofteethz2EditField           matlab.ui.control.NumericEditField
        Diameterd2EditFieldLabel        matlab.ui.control.Label
        Diameterd2EditField             matlab.ui.control.NumericEditField
        HelixangleEditField_4Label      matlab.ui.control.Label
        HelixangleEditField_4           matlab.ui.control.NumericEditField
        PressureangleEditField_4Label   matlab.ui.control.Label
        PressureangleEditField_4        matlab.ui.control.NumericEditField
        Gearwidthb2EditField_2Label     matlab.ui.control.Label
        Gearwidthb2EditField_2          matlab.ui.control.NumericEditField
        SetButton                       matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        ContactStressTab                matlab.ui.container.Tab
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        ElasticcoefficientZEEditField_2Label  matlab.ui.control.Label
        ElasticcoefficientZEEditField_2  matlab.ui.control.NumericEditField
        ZonefactorZHEditField_2Label    matlab.ui.control.Label
        ZonefactorZHEditField_2         matlab.ui.control.NumericEditField
        ContactratiofactorZEditField_2Label  matlab.ui.control.Label
        ContactratiofactorZEditField_2  matlab.ui.control.NumericEditField
        HelixanglefactorZEditField_2Label  matlab.ui.control.Label
        HelixanglefactorZEditField_2    matlab.ui.control.NumericEditField
        Computediameterd1EditField_2Label  matlab.ui.control.Label
        Computediameterd1EditField_2    matlab.ui.control.NumericEditField
        ComputemodulemnEditField_2Label  matlab.ui.control.Label
        ComputemodulemnEditField_2      matlab.ui.control.NumericEditField
        CentredistanceaEditField_2Label  matlab.ui.control.Label
        CentredistanceaEditField_2      matlab.ui.control.NumericEditField
        SofttoothsurfacecontactfatiguedesignButton  matlab.ui.control.Button
        BendingStressTab                matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        PinionTab_2                     matlab.ui.container.Tab
        ToothprofilefactorYFa1EditField_2Label  matlab.ui.control.Label
        ToothprofilefactorYFa1EditField_2  matlab.ui.control.NumericEditField
        StressconcentrationfactorYSa1EditField_4Label  matlab.ui.control.Label
        StressconcentrationfactorYSa1EditField_4  matlab.ui.control.NumericEditField
        ContactratiofactorYEditFieldLabel  matlab.ui.control.Label
        ContactratiofactorYEditField    matlab.ui.control.NumericEditField
        HelixanglefactorYEditFieldLabel  matlab.ui.control.Label
        HelixanglefactorYEditField      matlab.ui.control.NumericEditField
        GearTab_2                       matlab.ui.container.Tab
        ToothprofilefactorYFa2EditFieldLabel  matlab.ui.control.Label
        ToothprofilefactorYFa2EditField  matlab.ui.control.NumericEditField
        StressconcentrationfactorYSa2EditFieldLabel  matlab.ui.control.Label
        StressconcentrationfactorYSa2EditField  matlab.ui.control.NumericEditField
        ContactratiofactorYEditField_2Label  matlab.ui.control.Label
        ContactratiofactorYEditField_2  matlab.ui.control.NumericEditField
        HelixanglefactorYEditField_2Label  matlab.ui.control.Label
        HelixanglefactorYEditField_2    matlab.ui.control.NumericEditField
        SofttoothsurfacebendingfatiguecheckButton  matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        OutputTab                       matlab.ui.container.Tab
        SofttoothsurfacePanel           matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        BendingstressF1EditFieldLabel   matlab.ui.control.Label
        BendingstressF1EditField        matlab.ui.control.NumericEditField
        AllowablecontactstrengthF1EditField_2Label  matlab.ui.control.Label
        AllowablecontactstrengthF1EditField_2  matlab.ui.control.NumericEditField
        BendingstressF2EditFieldLabel   matlab.ui.control.Label
        BendingstressF2EditField        matlab.ui.control.NumericEditField
        AllowablecontactstrengthF2EditField_2Label  matlab.ui.control.Label
        AllowablecontactstrengthF2EditField_2  matlab.ui.control.NumericEditField
        ConclusionTextAreaLabel         matlab.ui.control.Label
        ConclusionTextArea              matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            drive=str2num(app.PrimemachineDropDown.Value);
            work=str2num(app.WorkingmachineDropDown_2.Value);
            Katable=[1 1.25 1.5 1.75;1.1 1.35 1.6 1.85;1.25 1.5 1.75 2;1.5 1.75 2 2.25];
            KA=Katable(drive,work);
            app.KAEditField_2.Value=KA;
            app.TextArea_3.Value=sprintf('%s%f%s','Under such condition, Ka=',KA');
            K=app.KAEditField_2.Value*app.KvEditField_2.Value*app.KaEditField_2.Value*app.KEditField_3.Value
            app.LoadfactorKEditField_2.Value=K;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            SHmin=1;
            YST=2;
            SFmin=1.25;
            j=1;
            
            Hlim1=app.ContactendurancelimitHlim1EditField_2.Value;
            KHn1=app.LifefactorKHn1EditField.Value;
            Hlim2=app.ContactendurancelimitHlim2EditField.Value;
            KHn2=app.LifefactorKHn2EditField.Value;
            Flim1=app.BendingendurancelimitFlim1EditField.Value;
            KFn1=app.LifefactorKFn1EditField.Value;
            Flim2=app.BendingendurancelimitFlim2EditField.Value;
            KFn2=app.LifefactorKFn2EditField.Value;
            
            app.AllowablecontactstrengthH1EditField_2.Value=Hlim1*KHn1/SHmin;
            app.AllowablecontactstrengthH2EditField.Value=Hlim2*KHn2/SHmin;
            app.AllowablecontactstrengthF1EditField.Value=Flim1*KFn1*YST/SFmin;
            app.AllowablecontactstrengthF2EditField.Value=Flim1*KFn2*YST/SFmin;
            
            app.Numberofcyclesn1EditField_3.Value=60*app.Rotationalspeedofthepinionn1EditField_2.Value*j*app.ServiceLifehEditField_2.Value;
            app.Numberofcyclesn2EditField.Value=app.Numberofcyclesn1EditField_3.Value/app.SpeedratioEditField_2.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            P=app.PowertransmittedPEditField_2.Value;
            u=app.SpeedratioEditField_2.Value;
            n1=app.Rotationalspeedofthepinionn1EditField_2.Value;
            T1=9.55*1e+6*P/n1;
            app.TorqueT1EditField_2.Value=T1;
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            if selectedTab==app.PinionTab
                app.TextArea.Value=['Please input z1, β.'...
                    ' It will output all blanks parameters.'];
            end
            if selectedTab==app.GearTab
                app.TextArea.Value=['U do not need to input any parameters, all based on z1 & β.'];
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            Be=app.HelixangleEditField_3.Value;
            z1=app.Numofteethz1EditField_3.Value;
            z2=ceil(z1*app.SpeedratioEditField_2.Value);
            app.Numofteethz2EditField.Value=z2;
            an=app.PressureangleEditField_3.Value;
            at=atand(tand(an)/cosd(Be));
            ea=(1.88-3.2*((1/z1)+(1/z2)))*cos(Be);
            app.ContactratiofactorZEditField_2.Value=sqrt((4-ea)/3);
            app.HelixanglefactorZEditField_2.Value=sqrt(cosd(Be));
            
            cosbb=sqrt(1-(sin(Be)*cos(an))^2);
            eav=ea/(cosbb^2);
            Ye=0.25+0.75/eav;
            app.ContactratiofactorYEditField.Value=Ye;
            app.ContactratiofactorYEditField_2.Value=Ye;
            
            eb=0.318*app.Slider_2.Value*z1*tan(Be);
            Yb=1-eb*Be/120;
            app.HelixanglefactorYEditField.Value=Yb;
            app.HelixanglefactorYEditField_2.Value=Yb;
            
            switch z1
                case 17
                app.ToothprofilefactorYFa1EditField_2.Value=2.97;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.52;
                case 18
                app.ToothprofilefactorYFa1EditField_2.Value=2.91;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.53;
                case 19
                app.ToothprofilefactorYFa1EditField_2.Value=2.85;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.54;
                case 20
                app.ToothprofilefactorYFa1EditField_2.Value=2.80;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.55;
                case 21
                app.ToothprofilefactorYFa1EditField_2.Value=2.76;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.56;
                case 22
                app.ToothprofilefactorYFa1EditField_2.Value=2.72;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.57;
                case 23
                app.ToothprofilefactorYFa1EditField_2.Value=2.69;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.575;
                case 24
                app.ToothprofilefactorYFa1EditField_2.Value=2.65;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.58;
                case 25
                app.ToothprofilefactorYFa1EditField_2.Value=2.62;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.59;
                case 26
                app.ToothprofilefactorYFa1EditField_2.Value=2.60;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.595;
                case 27
                app.ToothprofilefactorYFa1EditField_2.Value=2.57;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.60;
                case 28
                app.ToothprofilefactorYFa1EditField_2.Value=2.55;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.61;
                case 29
                app.ToothprofilefactorYFa1EditField_2.Value=2.53;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.62;
                case 30
                app.ToothprofilefactorYFa1EditField_2.Value=2.52;
                app.StressconcentrationfactorYSa1EditField_4.Value=1.625;
                otherwise 
                    app.TextArea.Value=['z1 is invalid, pleaz refill z1'];
            end
            
              switch true
                case z2==17
                app.ToothprofilefactorYFa2EditField.Value=2.97;
                app.StressconcentrationfactorYSa2EditField.Value=1.52;
                case z2==18
                app.ToothprofilefactorYFa2EditField.Value=2.91;
                app.StressconcentrationfactorYSa2EditField.Value=1.53;
                case z2==19
                app.ToothprofilefactorYFa2EditField.Value=2.85;
                app.StressconcentrationfactorYSa2EditField.Value=1.54;
                case z2==20
                app.ToothprofilefactorYFa2EditField.Value=2.80;
                app.StressconcentrationfactorYSa2EditField.Value=1.55;
                case z2==21
                app.ToothprofilefactorYFa2EditField.Value=2.76;
                app.StressconcentrationfactorYSa2EditField.Value=1.56;
                case z2==22
                app.ToothprofilefactorYFa2EditField.Value=2.72;
                app.StressconcentrationfactorYSa2EditField.Value=1.57;
                case z2==23
                app.ToothprofilefactorYFa2EditField.Value=2.69;
                app.StressconcentrationfactorYSa2EditField.Value=1.575;
                case z2==24
                app.ToothprofilefactorYFa2EditField.Value=2.65;
                app.StressconcentrationfactorYSa2EditField.Value=1.58;
                case z2==25
                app.ToothprofilefactorYFa2EditField.Value=2.62;
                app.StressconcentrationfactorYSa2EditField.Value=1.59;
                case z2==26
                app.ToothprofilefactorYFa2EditField.Value=2.60;
                app.StressconcentrationfactorYSa2EditField.Value=1.595;
                case z2==27
                app.ToothprofilefactorYFa2EditField.Value=2.57;
                app.StressconcentrationfactorYSa2EditField.Value=1.60;
                case z2==28
                app.ToothprofilefactorYFa2EditField.Value=2.55;
                app.StressconcentrationfactorYSa2EditField.Value=1.61;
                case z2==29
                app.ToothprofilefactorYFa2EditField.Value=2.53;
                app.StressconcentrationfactorYSa2EditField.Value=1.62;
                case z2>=30 && z2<35
                app.ToothprofilefactorYFa2EditField.Value=2.52;
                app.StressconcentrationfactorYSa2EditField.Value=1.625;
                case z2>=35 && z2<40
                app.ToothprofilefactorYFa2EditField.Value=2.45;
                app.StressconcentrationfactorYSa2EditField.Value=1.65;
                case z2>=40 && z2<45
                app.ToothprofilefactorYFa2EditField.Value=2.40;
                app.StressconcentrationfactorYSa2EditField.Value=1.67;
                case z2>=45 && z2<50
                app.ToothprofilefactorYFa2EditField.Value=2.35;
                app.StressconcentrationfactorYSa2EditField.Value=1.68;
                case z2>=50 && z2<60
                app.ToothprofilefactorYFa2EditField.Value=2.32;
                app.StressconcentrationfactorYSa2EditField.Value=1.70;
                case z2>=60 && z2<70
                app.ToothprofilefactorYFa2EditField.Value=2.28;
                app.StressconcentrationfactorYSa2EditField.Value=1.73;
                case z2>=70 && z2<80
                app.ToothprofilefactorYFa2EditField.Value=2.24;
                app.StressconcentrationfactorYSa2EditField.Value=1.75;
                case z2>=80 && z2<90
                app.ToothprofilefactorYFa2EditField.Value=2.22;
                app.StressconcentrationfactorYSa2EditField.Value=1.77;
                case z2>=90 && z2<100
                app.ToothprofilefactorYFa2EditField.Value=2.20;
                app.StressconcentrationfactorYSa2EditField.Value=1.78;
                case z2>=100 && z2<150
                app.ToothprofilefactorYFa2EditField.Value=2.18;
                app.StressconcentrationfactorYSa2EditField.Value=1.79;
                case z2>=150 && z2<200
                app.ToothprofilefactorYFa2EditField.Value=2.14;
                app.StressconcentrationfactorYSa2EditField.Value=1.83;
                case z2>=200 
                app.ToothprofilefactorYFa2EditField.Value=2.12;
                app.StressconcentrationfactorYSa2EditField.Value=1.865;
              end
                  
        end

        % Button pushed function: 
        % SofttoothsurfacecontactfatiguedesignButton
        function SofttoothsurfacecontactfatiguedesignButtonPushed(app, event)
            Be=app.HelixangleEditField_3.Value;
            z1=app.Numofteethz1EditField_3.Value;
            K=app.LoadfactorKEditField_2.Value;
            b_d = app.Slider_2.Value;
            u = app.SpeedratioEditField_2.Value;
            T1=app.TorqueT1EditField_2.Value;
            ZE=app.ElasticcoefficientZEEditField_2.Value;
            ZH=app.ZonefactorZHEditField_2.Value;
            Ze=app.ContactratiofactorZEditField_2.Value;
            Zbe=app.HelixanglefactorZEditField_2.Value;
            d_HP1=min(app.AllowablecontactstrengthH1EditField_2.Value,app.AllowablecontactstrengthH2EditField.Value);
            A=[(ZH*ZE*Ze*Zbe/d_HP1)^2,2*K*T1/b_d];
            if app.MeshingmethodDropDown.Value=='1'
                    Zx=(u-1)/u;
                    d1=(prod(A)*Zx)^(1/3);
            else
                    Zx=(u+1)/u;
                    d1=(prod(A)*Zx)^(1/3);
            end
            app.Computediameterd1EditField_2.Value=d1;
            mn=d1*cosd(Be)/z1;
            app.ComputemodulemnEditField_2.Value=roundn(mn,-3);
            app.TextArea.Value='Pleaz modify mn to standard value and check center distance a';
        end

        % Value changed function: ComputemodulemnEditField_2
        function ComputemodulemnEditField_2ValueChanged(app, event)
            value = app.ComputemodulemnEditField_2.Value;
            mn=app.ComputemodulemnEditField_2.Value;
            z1=app.Numofteethz1EditField_3.Value;
            z2=app.Numofteethz2EditField.Value;
            Be=app.HelixangleEditField_3.Value;
            a=mn*(1/cos(Be))*(z1+z2)/2;
            app.CentredistanceaEditField_2.Value=roundn(a,-3); 
            app.TextArea.Value='Pleaz modify a to an integer';
        end

        % Value changed function: CentredistanceaEditField_2
        function CentredistanceaEditField_2ValueChanged(app, event)
            value = app.CentredistanceaEditField_2.Value;
            mn=app.ComputemodulemnEditField_2.Value;
            z1=app.Numofteethz1EditField_3.Value;
            z2=app.Numofteethz2EditField.Value;
            a=value;
            Be=app.HelixangleEditField_3.Value;
            
            if Be ==0
                Bt = 0;
            else
                Bt=mn*(z1+z2)/(2*a);
                Be=acosd(Bt);
            end
            app.HelixangleEditField_3.Value=Be;
            app.HelixangleEditField_4.Value=Be;
            app.HelixangleEditField_3.FontColor='y';
            app.HelixangleEditField_4.FontColor='y';
            d1=mn*z1/cos(Be);
            d2=mn*z2/cos(Be);
            app.Diameterd1EditField_3.Value=d1;
            app.Diameterd1EditField_3.FontColor='y';
            app.Diameterd2EditField.Value=d2;
            app.Diameterd2EditField.FontColor='y';
        end

        % Selection change function: TabGroup4
        function TabGroup4SelectionChanged(app, event)
            selectedTab = app.TabGroup4.SelectedTab;
            if selectedTab==app.BendingStressTab
                app.TextArea.Value=['Pleaz make sure a and mn are modified in order to complish the fatigue strength check!'];
            end
        end

        % Button pushed function: 
        % SofttoothsurfacebendingfatiguecheckButton
        function SofttoothsurfacebendingfatiguecheckButtonPushed(app, event)
           YFa1= app.ToothprofilefactorYFa1EditField_2.Value;
           YSa1= app.StressconcentrationfactorYSa1EditField_4.Value;
           YFa2= app.ToothprofilefactorYFa2EditField.Value;
           YSa2= app.StressconcentrationfactorYSa2EditField.Value;
           YE= app.ContactratiofactorYEditField.Value;
           YB= app.HelixanglefactorYEditField.Value;
           K=app.LoadfactorKEditField_2.Value;
           T1=app.TorqueT1EditField_2.Value;
           d1=app.Diameterd1EditField_3.Value;
           mn=app.ComputemodulemnEditField_2.Value;
           b=d1*app.Slider_2.Value;
           
           A1=[2*K*T1/b/d1/mn,YFa1,YSa1,YE,YB];
           A2=[2*K*T1/b/d1/mn,YFa2,YSa2,YE,YB];
           F1=prod(A1);
           F2=prod(A2);
           
           app.BendingstressF1EditField.Value=F1;
           app.BendingstressF2EditField.Value=F2;
           FP1=app.AllowablecontactstrengthF1EditField.Value;
           app.AllowablecontactstrengthF1EditField_2.Value=FP1;
           FP2=app.AllowablecontactstrengthF2EditField.Value;
           app.AllowablecontactstrengthF2EditField_2.Value=FP2;
           app.TextArea.Value=['Pleaz check out the results on output panel.'];
           
           if F1<FP1
                 app.ConclusionTextArea.Value='Go for it';
                 app.Lamp.Color='g';
           else
                 app.result.Value='Pleaz redesign.';
                 app.Lamp.Color='r';
           end
           
           if F2<FP2
                 app.ConclusionTextArea.Value='Go for it';
                 app.Lamp_2.Color='g';
           else
                 app.result.Value='Pleaz redesign.';
                 app.Lamp_2.Color='r';
           end
        end

        % Callback function
        function CalculateButton_4Pushed(app, event)
             d = app.DiameterofthegeardEditField.Value;
             d1 = app.DiameteroftheshaftatthegearEditField.Value;
             T = app.OutputtorqueTEditField.Value;
             b = app.gearwidthbEditField.Value/2;
             l2 = app.L2EditField.Value;
             l3 = app.L3EditField.Value;
             kc1 = app.StressconcentrationfactorforbendingkEditField.Value;
             kt1 = app.StressconcentrationfactorforshearingkEditField.Value;
             ec = app.SizefactorforbendingEditField_2.Value;
             et = app.SizefactorforshearingEditField_2.Value;
             Sr = app.RequiredsafetyfactorSrEditField.Value;
             c11 = app.Allowablestress1bEditField.Value;
             Ft=2*T/d;
             Fr=Ft*tan(20);
             syms RH1 RH2 %reaction force outer inner horizental
             [RH1,RH2]=solve(RH1+RH2==Fr,RH1*l2==RH2*l3);
             syms RV1 RV2 %reaction force outer inner vertical
             [RV1,RV2]=solve(RV1+RV2==Ft,RV1*l2==RV2*l3);
             MH=RH1*l2;
             MV=RV1*l2;
             M=sqrt(MH^2+MV^2);
             %strength calculation
             a=0.6;
             w=0.1*d1^3;
             cca=sqrt(M^2+(a*T)^2)/w;%max stress
             if c11>cca
                 ss1=('your shaft is safe');
                 app.Lamp_3.Color='g';
             else
                 ss1=('your shaft is not safe');
                 app.Lamp_3.Color='r';
             end
             app.ConclusionTextArea_2.Value=ss1;
             app.ShaftcheckbystressEditField.Value=double(cca);
             
             %fatigue
             w=0.1*d1^3;
             M1=(l2-b/l2)*M;
             c=M1/w;%bending stress
             t=T/(2*w);%shearing stress
             c1=270;t1=155;t0=295;
             ft=(2*t1-t0)/t0;
             bc=0.78;bt=0.78;bq=1;
             kc=(kc1/ec+1/bc-1)*(1/bq);
             kt=(kt1/et+1/bt-1)*(1/bq);
             Sc=c1/(kc*c);
             St=t1/(kt*t/2+ft*t/2);
             S=Sc*St/(sqrt(Sc^2+St^2));
             if S>Sr
                 ss2=('your shaft is safe');
                 app.Lamp_4.Color='g';
             else
                 ss2=('your shaft is not safe');
                 app.Lamp_4.Color='r';
             end
             app.ConclusionTextArea_3.Value=ss2;
             app.ShaftcheckbyfatigueEditField.Value=double(S);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CoursedesignLabel = uilabel(app.UIFigure);
            app.CoursedesignLabel.Text = 'Course design';
            app.TabGroup6 = uitabgroup(app.UIFigure);
            app.GearDesignTab = uitab(app.TabGroup6);
            app.GearDesignTab.Title = 'Gear Design';
            app.TabGroup = uitabgroup(app.GearDesignTab);
            app.WorkingConditionsTab = uitab(app.TabGroup);
            app.WorkingConditionsTab.Title = 'Working Conditions';
            app.TabGroup2 = uitabgroup(app.WorkingConditionsTab);
            app.InputTab = uitab(app.TabGroup2);
            app.InputTab.Title = 'Input';
            app.InputPanel = uipanel(app.InputTab);
            app.InputPanel.Title = 'Input';
            app.KWLabel = uilabel(app.InputPanel);
            app.KWLabel.Text = 'KW';
            app.rpmLabel = uilabel(app.InputPanel);
            app.rpmLabel.Text = 'rpm';
            app.hLabel = uilabel(app.InputPanel);
            app.hLabel.Text = 'h';
            app.MeshingmethodDropDownLabel = uilabel(app.InputPanel);
            app.MeshingmethodDropDownLabel.Text = 'Meshing method';
            app.MeshingmethodDropDown = uidropdown(app.InputPanel);
            app.MeshingmethodDropDown.Items = {'Internal meshing', 'External meshing'};
            app.MeshingmethodDropDown.ItemsData = {'1', '2'};
            app.MeshingmethodDropDown.Value = '2';
            app.SupportingEditFieldLabel = uilabel(app.InputPanel);
            app.SupportingEditFieldLabel.Text = 'Supporting';
            app.SupportingEditField = uieditfield(app.InputPanel, 'text');
            app.SupportingEditField.Value = 'symmetrical';
            app.TextArea_5 = uitextarea(app.InputPanel);
            app.TextArea_5.Value = {'Please fill all blanks and it will give the torque T1 automatically.'};
            app.TextArea_6 = uitextarea(app.InputPanel);
            app.TextArea_6.Value = {'T1=9550*P/n1'};
            app.PowertransmittedPEditField_2Label = uilabel(app.InputPanel);
            app.PowertransmittedPEditField_2Label.Text = 'Power transmitted P';
            app.PowertransmittedPEditField_2 = uieditfield(app.InputPanel, 'numeric');
            app.PowertransmittedPEditField_2.Value = 4.36;
            app.SpeedratioEditField_2Label = uilabel(app.InputPanel);
            app.SpeedratioEditField_2Label.Text = 'Speed ratio ';
            app.SpeedratioEditField_2 = uieditfield(app.InputPanel, 'numeric');
            app.SpeedratioEditField_2.Value = 6.245;
            app.Rotationalspeedofthepinionn1EditField_2Label = uilabel(app.InputPanel);
            app.Rotationalspeedofthepinionn1EditField_2Label.Text = 'Rotational speed of the pinion n1';
            app.Rotationalspeedofthepinionn1EditField_2 = uieditfield(app.InputPanel, 'numeric');
            app.Rotationalspeedofthepinionn1EditField_2.Value = 331;
            app.ServiceLifehEditField_2Label = uilabel(app.InputPanel);
            app.ServiceLifehEditField_2Label.Text = 'Service Life h';
            app.ServiceLifehEditField_2 = uieditfield(app.InputPanel, 'numeric');
            app.ServiceLifehEditField_2.Value = 58400;
            app.CallbackPanel = uipanel(app.InputTab);
            app.CallbackPanel.Title = 'Callback';
            app.CalculateButton = uibutton(app.CallbackPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NmmLabel = uilabel(app.CallbackPanel);
            app.NmmLabel.Text = 'N*mm';
            app.TorqueT1EditField_2Label = uilabel(app.CallbackPanel);
            app.TorqueT1EditField_2Label.Text = 'Torque T1';
            app.TorqueT1EditField_2 = uieditfield(app.CallbackPanel, 'numeric');
            app.TorqueT1EditField_2.ValueDisplayFormat = '%11.8g';
            app.AllowTab = uitab(app.TabGroup2);
            app.AllowTab.Title = 'Allow';
            app.GearPanel = uipanel(app.AllowTab);
            app.GearPanel.Title = 'Gear';
            app.AllowancecontactstressPanel_2 = uipanel(app.GearPanel);
            app.AllowancecontactstressPanel_2.Title = 'Allowance contact stress';
            app.MpaLabel_3 = uilabel(app.AllowancecontactstressPanel_2);
            app.MpaLabel_3.Text = 'Mpa';
            app.MpaLabel_4 = uilabel(app.AllowancecontactstressPanel_2);
            app.MpaLabel_4.Text = 'Mpa';
            app.ContactendurancelimitHlim2EditFieldLabel = uilabel(app.AllowancecontactstressPanel_2);
            app.ContactendurancelimitHlim2EditFieldLabel.Text = {'Contact endurance limit'; ''; '  σHlim2'};
            app.ContactendurancelimitHlim2EditField = uieditfield(app.AllowancecontactstressPanel_2, 'numeric');
            app.ContactendurancelimitHlim2EditField.Value = 550;
            app.LifefactorKHn2EditFieldLabel = uilabel(app.AllowancecontactstressPanel_2);
            app.LifefactorKHn2EditFieldLabel.Text = 'Life factor KHn2';
            app.LifefactorKHn2EditField = uieditfield(app.AllowancecontactstressPanel_2, 'numeric');
            app.LifefactorKHn2EditField.Value = 0.92;
            app.AllowablecontactstrengthH2EditFieldLabel = uilabel(app.AllowancecontactstressPanel_2);
            app.AllowablecontactstrengthH2EditFieldLabel.Text = {'Allowable contact strength'; ''; '  [σH]2'};
            app.AllowablecontactstrengthH2EditField = uieditfield(app.AllowancecontactstressPanel_2, 'numeric');
            app.Label_12 = uilabel(app.AllowancecontactstressPanel_2);
            app.Label_12.Text = '_____________________________________________';
            app.InputLabel_3 = uilabel(app.AllowancecontactstressPanel_2);
            app.InputLabel_3.Text = 'Input';
            app.OutputLabel_3 = uilabel(app.AllowancecontactstressPanel_2);
            app.OutputLabel_3.Text = 'Output';
            app.AllowancebendingstressPanel_2 = uipanel(app.GearPanel);
            app.AllowancebendingstressPanel_2.Title = 'Allowance bending stress';
            app.MpaLabel_7 = uilabel(app.AllowancebendingstressPanel_2);
            app.MpaLabel_7.Text = 'Mpa';
            app.MpaLabel_8 = uilabel(app.AllowancebendingstressPanel_2);
            app.MpaLabel_8.Text = 'Mpa';
            app.BendingendurancelimitFlim2EditFieldLabel = uilabel(app.AllowancebendingstressPanel_2);
            app.BendingendurancelimitFlim2EditFieldLabel.Text = {'Bending endurance limit'; ''; '  σFlim2'};
            app.BendingendurancelimitFlim2EditField = uieditfield(app.AllowancebendingstressPanel_2, 'numeric');
            app.BendingendurancelimitFlim2EditField.Value = 170;
            app.LifefactorKFn2EditFieldLabel = uilabel(app.AllowancebendingstressPanel_2);
            app.LifefactorKFn2EditFieldLabel.Text = 'Life factor KFn2';
            app.LifefactorKFn2EditField = uieditfield(app.AllowancebendingstressPanel_2, 'numeric');
            app.LifefactorKFn2EditField.Value = 0.88;
            app.AllowablecontactstrengthF2EditFieldLabel = uilabel(app.AllowancebendingstressPanel_2);
            app.AllowablecontactstrengthF2EditFieldLabel.Text = {'Allowable contact strength'; ''; '  [σF]2'};
            app.AllowablecontactstrengthF2EditField = uieditfield(app.AllowancebendingstressPanel_2, 'numeric');
            app.Label_13 = uilabel(app.AllowancebendingstressPanel_2);
            app.Label_13.Text = '__________________________________________________';
            app.InputLabel_4 = uilabel(app.AllowancebendingstressPanel_2);
            app.InputLabel_4.Text = 'Input';
            app.OutputLabel_4 = uilabel(app.AllowancebendingstressPanel_2);
            app.OutputLabel_4.Text = 'Output';
            app.CalculateButton_2 = uibutton(app.AllowTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PinionPanel = uipanel(app.AllowTab);
            app.PinionPanel.Title = 'Pinion';
            app.AllowancecontactstressPanel = uipanel(app.PinionPanel);
            app.AllowancecontactstressPanel.Title = 'Allowance contact stress';
            app.MpaLabel = uilabel(app.AllowancecontactstressPanel);
            app.MpaLabel.Text = 'Mpa';
            app.MpaLabel_2 = uilabel(app.AllowancecontactstressPanel);
            app.MpaLabel_2.Text = 'Mpa';
            app.ContactendurancelimitHlim1EditField_2Label = uilabel(app.AllowancecontactstressPanel);
            app.ContactendurancelimitHlim1EditField_2Label.Text = {'Contact endurance limit'; ''; '  σHlim1'};
            app.ContactendurancelimitHlim1EditField_2 = uieditfield(app.AllowancecontactstressPanel, 'numeric');
            app.ContactendurancelimitHlim1EditField_2.Value = 575;
            app.LifefactorKHn1EditFieldLabel = uilabel(app.AllowancecontactstressPanel);
            app.LifefactorKHn1EditFieldLabel.Text = 'Life factor KHn1';
            app.LifefactorKHn1EditField = uieditfield(app.AllowancecontactstressPanel, 'numeric');
            app.LifefactorKHn1EditField.Value = 0.87;
            app.AllowablecontactstrengthH1Label = uilabel(app.AllowancecontactstressPanel);
            app.AllowablecontactstrengthH1Label.Text = {'Allowable contact strength'; ''; '  [σH]1'};
            app.AllowablecontactstrengthH1EditField_2 = uieditfield(app.AllowancecontactstressPanel, 'numeric');
            app.Label_10 = uilabel(app.AllowancecontactstressPanel);
            app.Label_10.Text = '_____________________________________________';
            app.InputLabel = uilabel(app.AllowancecontactstressPanel);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.AllowancecontactstressPanel);
            app.OutputLabel.Text = 'Output';
            app.AllowancebendingstressPanel = uipanel(app.PinionPanel);
            app.AllowancebendingstressPanel.Title = 'Allowance bending stress';
            app.MpaLabel_5 = uilabel(app.AllowancebendingstressPanel);
            app.MpaLabel_5.Text = 'Mpa';
            app.MpaLabel_6 = uilabel(app.AllowancebendingstressPanel);
            app.MpaLabel_6.Text = 'Mpa';
            app.BendingendurancelimitFlim1EditFieldLabel = uilabel(app.AllowancebendingstressPanel);
            app.BendingendurancelimitFlim1EditFieldLabel.Text = {'Bending endurance limit'; ''; '  σFlim1'};
            app.BendingendurancelimitFlim1EditField = uieditfield(app.AllowancebendingstressPanel, 'numeric');
            app.BendingendurancelimitFlim1EditField.Value = 225;
            app.LifefactorKFn1EditFieldLabel = uilabel(app.AllowancebendingstressPanel);
            app.LifefactorKFn1EditFieldLabel.Text = 'Life factor KFn1';
            app.LifefactorKFn1EditField = uieditfield(app.AllowancebendingstressPanel, 'numeric');
            app.LifefactorKFn1EditField.Value = 0.85;
            app.AllowablecontactstrengthF1EditFieldLabel = uilabel(app.AllowancebendingstressPanel);
            app.AllowablecontactstrengthF1EditFieldLabel.Text = {'Allowable contact strength'; ''; '  [σF]1'};
            app.AllowablecontactstrengthF1EditField = uieditfield(app.AllowancebendingstressPanel, 'numeric');
            app.Label_11 = uilabel(app.AllowancebendingstressPanel);
            app.Label_11.Text = '__________________________________________________';
            app.InputLabel_2 = uilabel(app.AllowancebendingstressPanel);
            app.InputLabel_2.Text = 'Input';
            app.OutputLabel_2 = uilabel(app.AllowancebendingstressPanel);
            app.OutputLabel_2.Text = 'Output';
            app.Numberofcyclesn1EditField_3Label = uilabel(app.AllowTab);
            app.Numberofcyclesn1EditField_3Label.Text = 'Number of cycles n1';
            app.Numberofcyclesn1EditField_3 = uieditfield(app.AllowTab, 'numeric');
            app.Numberofcyclesn2EditFieldLabel = uilabel(app.AllowTab);
            app.Numberofcyclesn2EditFieldLabel.Text = 'Number of cycles n2';
            app.Numberofcyclesn2EditField = uieditfield(app.AllowTab, 'numeric');
            app.LoadTab = uitab(app.TabGroup2);
            app.LoadTab.Title = 'Load';
            app.ApplicationfactorKAPanel = uipanel(app.LoadTab);
            app.ApplicationfactorKAPanel.Title = 'Application factor KA';
            app.PrimemachineDropDownLabel = uilabel(app.ApplicationfactorKAPanel);
            app.PrimemachineDropDownLabel.Text = 'Prime machine';
            app.PrimemachineDropDown = uidropdown(app.ApplicationfactorKAPanel);
            app.PrimemachineDropDown.Items = {'Uniform', 'Light impact', 'Moderate impact', ' heavy impact'};
            app.PrimemachineDropDown.ItemsData = {'1', '2', '3', '4'};
            app.PrimemachineDropDown.Editable = 'on';
            app.PrimemachineDropDown.Value = '1';
            app.WorkingmachineDropDown_2Label = uilabel(app.ApplicationfactorKAPanel);
            app.WorkingmachineDropDown_2Label.Text = 'Working machine';
            app.WorkingmachineDropDown_2 = uidropdown(app.ApplicationfactorKAPanel);
            app.WorkingmachineDropDown_2.Items = {'Uniform', 'Light impact', 'Moderate impact', 'Heavy impact'};
            app.WorkingmachineDropDown_2.ItemsData = {'1', '2', '3', '4'};
            app.WorkingmachineDropDown_2.Value = '1';
            app.TextArea_3 = uitextarea(app.ApplicationfactorKAPanel);
            app.KAEditField_2Label = uilabel(app.ApplicationfactorKAPanel);
            app.KAEditField_2Label.Text = 'KA';
            app.KAEditField_2 = uieditfield(app.ApplicationfactorKAPanel, 'numeric');
            app.DynamicfactorKvPanel = uipanel(app.LoadTab);
            app.DynamicfactorKvPanel.Title = 'Dynamic factor Kv';
            app.TextArea_4 = uitextarea(app.DynamicfactorKvPanel);
            app.KvEditField_2Label = uilabel(app.DynamicfactorKvPanel);
            app.KvEditField_2Label.Text = 'Kv';
            app.KvEditField_2 = uieditfield(app.DynamicfactorKvPanel, 'numeric');
            app.KvEditField_2.Value = 1.07;
            app.LoaddistributionfactorintoothdirectionKPanel = uipanel(app.LoadTab);
            app.LoaddistributionfactorintoothdirectionKPanel.Title = 'Load distribution factor in tooth direction Kβ';
            app.TextArea2_2 = uitextarea(app.LoaddistributionfactorintoothdirectionKPanel);
            app.KEditField_3Label = uilabel(app.LoaddistributionfactorintoothdirectionKPanel);
            app.KEditField_3Label.Text = 'Kβ';
            app.KEditField_3 = uieditfield(app.LoaddistributionfactorintoothdirectionKPanel, 'numeric');
            app.KEditField_3.Value = 1.2727;
            app.CalculateButton_3 = uibutton(app.LoadTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.Panel7 = uipanel(app.LoadTab);
            app.Panel7.Title = 'Load distribution factor Ka';
            app.TextArea2 = uitextarea(app.Panel7);
            app.KaEditField_2Label = uilabel(app.Panel7);
            app.KaEditField_2Label.Text = 'Ka';
            app.KaEditField_2 = uieditfield(app.Panel7, 'numeric');
            app.KaEditField_2.Value = 1.1;
            app.LoadfactorKEditField_2Label = uilabel(app.LoadTab);
            app.LoadfactorKEditField_2Label.Text = 'Load factor K';
            app.LoadfactorKEditField_2 = uieditfield(app.LoadTab, 'numeric');
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.HeattreatmentPanel = uipanel(app.ParametersTab);
            app.HeattreatmentPanel.Title = 'Heat treatment';
            app.HeattreatmentEditFieldLabel = uilabel(app.HeattreatmentPanel);
            app.HeattreatmentEditFieldLabel.Text = 'Heat treatment';
            app.HeattreatmentEditField = uieditfield(app.HeattreatmentPanel, 'text');
            app.HeattreatmentEditField.Value = 'Tempering';
            app.PinionmaterialDropDownLabel = uilabel(app.HeattreatmentPanel);
            app.PinionmaterialDropDownLabel.Text = 'Pinion material';
            app.PinionmaterialDropDown = uidropdown(app.HeattreatmentPanel);
            app.PinionmaterialDropDown.Items = {'40MnB', '40Cr'};
            app.PinionmaterialDropDown.Value = '40MnB';
            app.GearmaterialEditFieldLabel = uilabel(app.HeattreatmentPanel);
            app.GearmaterialEditFieldLabel.Text = 'Gear material';
            app.GearmaterialEditField = uieditfield(app.HeattreatmentPanel, 'text');
            app.GearmaterialEditField.Value = '45 carbon steel';
            app.HeattreatmentEditField_2Label = uilabel(app.HeattreatmentPanel);
            app.HeattreatmentEditField_2Label.Text = 'Heat treatment';
            app.HeattreatmentEditField_2 = uieditfield(app.HeattreatmentPanel, 'text');
            app.HeattreatmentEditField_2.Value = 'Tempering';
            app.Label = uilabel(app.ParametersTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.ParametersTab);
            app.TabGroup3 = uitabgroup(app.ParametersTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.PinionTab = uitab(app.TabGroup3);
            app.PinionTab.Title = 'Pinion';
            app.mmLabel_2 = uilabel(app.PinionTab);
            app.mmLabel_2.Text = 'mm';
            app.Slider_2 = uislider(app.PinionTab);
            app.Slider_2.Limits = [0.8 1.4];
            app.Slider_2.MajorTicks = [0.8 0.9 1 1.1 1.2 1.4];
            app.Slider_2.Value = 1;
            app.Label_5 = uilabel(app.PinionTab);
            app.Label_5.Text = '°';
            app.ToothwidthratiodLabel = uilabel(app.PinionTab);
            app.ToothwidthratiodLabel.Text = 'Tooth width ratio φd';
            app.Numofteethz1EditField_3Label = uilabel(app.PinionTab);
            app.Numofteethz1EditField_3Label.Text = 'Num of teeth z1';
            app.Numofteethz1EditField_3 = uieditfield(app.PinionTab, 'numeric');
            app.Numofteethz1EditField_3.Value = 19;
            app.Diameterd1EditField_3Label = uilabel(app.PinionTab);
            app.Diameterd1EditField_3Label.Text = 'Diameter d1';
            app.Diameterd1EditField_3 = uieditfield(app.PinionTab, 'numeric');
            app.HelixangleEditField_3Label = uilabel(app.PinionTab);
            app.HelixangleEditField_3Label.Text = 'Helix angle β';
            app.HelixangleEditField_3 = uieditfield(app.PinionTab, 'numeric');
            app.PressureangleEditField_3Label = uilabel(app.PinionTab);
            app.PressureangleEditField_3Label.Text = 'Pressure angle';
            app.PressureangleEditField_3 = uieditfield(app.PinionTab, 'numeric');
            app.PressureangleEditField_3.Value = 20;
            app.Label_14 = uilabel(app.PinionTab);
            app.Label_14.Text = '°';
            app.GearTab = uitab(app.TabGroup3);
            app.GearTab.Title = 'Gear';
            app.Label_4 = uilabel(app.GearTab);
            app.Label_4.Text = '°';
            app.mmLabel = uilabel(app.GearTab);
            app.mmLabel.Text = 'mm';
            app.Label_3 = uilabel(app.GearTab);
            app.Label_3.Text = '°';
            app.mmLabel_3 = uilabel(app.GearTab);
            app.mmLabel_3.Text = 'mm';
            app.Numofteethz2EditFieldLabel = uilabel(app.GearTab);
            app.Numofteethz2EditFieldLabel.Text = 'Num of teeth z2';
            app.Numofteethz2EditField = uieditfield(app.GearTab, 'numeric');
            app.Diameterd2EditFieldLabel = uilabel(app.GearTab);
            app.Diameterd2EditFieldLabel.Text = 'Diameter d2';
            app.Diameterd2EditField = uieditfield(app.GearTab, 'numeric');
            app.HelixangleEditField_4Label = uilabel(app.GearTab);
            app.HelixangleEditField_4Label.Text = 'Helix angle β';
            app.HelixangleEditField_4 = uieditfield(app.GearTab, 'numeric');
            app.PressureangleEditField_4Label = uilabel(app.GearTab);
            app.PressureangleEditField_4Label.Text = 'Pressure angle';
            app.PressureangleEditField_4 = uieditfield(app.GearTab, 'numeric');
            app.PressureangleEditField_4.Value = 20;
            app.Gearwidthb2EditField_2Label = uilabel(app.GearTab);
            app.Gearwidthb2EditField_2Label.Text = 'Gear width b2';
            app.Gearwidthb2EditField_2 = uieditfield(app.GearTab, 'numeric');
            app.SetButton = uibutton(app.ParametersTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.TabGroup4 = uitabgroup(app.ParametersTab);
            app.TabGroup4.SelectionChangedFcn = createCallbackFcn(app, @TabGroup4SelectionChanged, true);
            app.ContactStressTab = uitab(app.TabGroup4);
            app.ContactStressTab.Title = 'Contact Stress';
            app.mmLabel_4 = uilabel(app.ContactStressTab);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.ContactStressTab);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.ContactStressTab);
            app.mmLabel_6.Text = 'mm';
            app.Label_7 = uilabel(app.ContactStressTab);
            app.Label_7.Text = '';
            app.ElasticcoefficientZEEditField_2Label = uilabel(app.ContactStressTab);
            app.ElasticcoefficientZEEditField_2Label.Text = 'Elastic coefficient ZE';
            app.ElasticcoefficientZEEditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.ElasticcoefficientZEEditField_2.Value = 188.9;
            app.ZonefactorZHEditField_2Label = uilabel(app.ContactStressTab);
            app.ZonefactorZHEditField_2Label.Text = 'Zone factor ZH';
            app.ZonefactorZHEditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.ZonefactorZHEditField_2.Value = 2.5;
            app.ContactratiofactorZEditField_2Label = uilabel(app.ContactStressTab);
            app.ContactratiofactorZEditField_2Label.Text = 'Contact ratio factor Zε';
            app.ContactratiofactorZEditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.HelixanglefactorZEditField_2Label = uilabel(app.ContactStressTab);
            app.HelixanglefactorZEditField_2Label.Text = 'Helix angle factor Zβ';
            app.HelixanglefactorZEditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.Computediameterd1EditField_2Label = uilabel(app.ContactStressTab);
            app.Computediameterd1EditField_2Label.Text = 'Compute diameter d1';
            app.Computediameterd1EditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.ComputemodulemnEditField_2Label = uilabel(app.ContactStressTab);
            app.ComputemodulemnEditField_2Label.Text = 'Compute module mn';
            app.ComputemodulemnEditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.ComputemodulemnEditField_2.ValueChangedFcn = createCallbackFcn(app, @ComputemodulemnEditField_2ValueChanged, true);
            app.CentredistanceaEditField_2Label = uilabel(app.ContactStressTab);
            app.CentredistanceaEditField_2Label.Text = 'Centre distance a';
            app.CentredistanceaEditField_2 = uieditfield(app.ContactStressTab, 'numeric');
            app.CentredistanceaEditField_2.ValueChangedFcn = createCallbackFcn(app, @CentredistanceaEditField_2ValueChanged, true);
            app.SofttoothsurfacecontactfatiguedesignButton = uibutton(app.ContactStressTab, 'push');
            app.SofttoothsurfacecontactfatiguedesignButton.ButtonPushedFcn = createCallbackFcn(app, @SofttoothsurfacecontactfatiguedesignButtonPushed, true);
            app.SofttoothsurfacecontactfatiguedesignButton.Text = 'Soft tooth surface contact fatigue design';
            app.BendingStressTab = uitab(app.TabGroup4);
            app.BendingStressTab.Title = 'Bending Stress';
            app.TabGroup5 = uitabgroup(app.BendingStressTab);
            app.PinionTab_2 = uitab(app.TabGroup5);
            app.PinionTab_2.Title = 'Pinion';
            app.ToothprofilefactorYFa1EditField_2Label = uilabel(app.PinionTab_2);
            app.ToothprofilefactorYFa1EditField_2Label.Text = {'Tooth profile factor'; ''; 'YFa1'};
            app.ToothprofilefactorYFa1EditField_2 = uieditfield(app.PinionTab_2, 'numeric');
            app.StressconcentrationfactorYSa1EditField_4Label = uilabel(app.PinionTab_2);
            app.StressconcentrationfactorYSa1EditField_4Label.Text = {'Stress concentration factor'; ''; 'YSa1'};
            app.StressconcentrationfactorYSa1EditField_4 = uieditfield(app.PinionTab_2, 'numeric');
            app.ContactratiofactorYEditFieldLabel = uilabel(app.PinionTab_2);
            app.ContactratiofactorYEditFieldLabel.Text = {'Contact ratio factor'; ''; 'Yε'};
            app.ContactratiofactorYEditField = uieditfield(app.PinionTab_2, 'numeric');
            app.HelixanglefactorYEditFieldLabel = uilabel(app.PinionTab_2);
            app.HelixanglefactorYEditFieldLabel.Text = {'Helix angle factor'; ''; 'Yβ'};
            app.HelixanglefactorYEditField = uieditfield(app.PinionTab_2, 'numeric');
            app.GearTab_2 = uitab(app.TabGroup5);
            app.GearTab_2.Title = 'Gear';
            app.ToothprofilefactorYFa2EditFieldLabel = uilabel(app.GearTab_2);
            app.ToothprofilefactorYFa2EditFieldLabel.Text = {'Tooth profile factor'; ''; 'YFa2'};
            app.ToothprofilefactorYFa2EditField = uieditfield(app.GearTab_2, 'numeric');
            app.StressconcentrationfactorYSa2EditFieldLabel = uilabel(app.GearTab_2);
            app.StressconcentrationfactorYSa2EditFieldLabel.Text = {'Stress concentration factor'; ''; 'YSa2'};
            app.StressconcentrationfactorYSa2EditField = uieditfield(app.GearTab_2, 'numeric');
            app.ContactratiofactorYEditField_2Label = uilabel(app.GearTab_2);
            app.ContactratiofactorYEditField_2Label.Text = {'Contact ratio factor'; ''; 'Yε'};
            app.ContactratiofactorYEditField_2 = uieditfield(app.GearTab_2, 'numeric');
            app.HelixanglefactorYEditField_2Label = uilabel(app.GearTab_2);
            app.HelixanglefactorYEditField_2Label.Text = {'Helix angle factor'; ''; 'Yβ'};
            app.HelixanglefactorYEditField_2 = uieditfield(app.GearTab_2, 'numeric');
            app.SofttoothsurfacebendingfatiguecheckButton = uibutton(app.GearTab_2, 'push');
            app.SofttoothsurfacebendingfatiguecheckButton.ButtonPushedFcn = createCallbackFcn(app, @SofttoothsurfacebendingfatiguecheckButtonPushed, true);
            app.SofttoothsurfacebendingfatiguecheckButton.Text = {'Soft tooth surface '; 'bending fatigue check'};
            app.Label_9 = uilabel(app.ParametersTab);
            app.Label_9.Text = '___________________________________________';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.SofttoothsurfacePanel = uipanel(app.OutputTab);
            app.SofttoothsurfacePanel.Title = 'Soft tooth surface';
            app.Lamp = uilamp(app.SofttoothsurfacePanel);
            app.Lamp_2 = uilamp(app.SofttoothsurfacePanel);
            app.BendingstressF1EditFieldLabel = uilabel(app.SofttoothsurfacePanel);
            app.BendingstressF1EditFieldLabel.Text = 'Bending stress σF1';
            app.BendingstressF1EditField = uieditfield(app.SofttoothsurfacePanel, 'numeric');
            app.AllowablecontactstrengthF1EditField_2Label = uilabel(app.SofttoothsurfacePanel);
            app.AllowablecontactstrengthF1EditField_2Label.Text = 'Allowable contact strength [σF]1';
            app.AllowablecontactstrengthF1EditField_2 = uieditfield(app.SofttoothsurfacePanel, 'numeric');
            app.BendingstressF2EditFieldLabel = uilabel(app.SofttoothsurfacePanel);
            app.BendingstressF2EditFieldLabel.Text = 'Bending stress σF2';
            app.BendingstressF2EditField = uieditfield(app.SofttoothsurfacePanel, 'numeric');
            app.AllowablecontactstrengthF2EditField_2Label = uilabel(app.SofttoothsurfacePanel);
            app.AllowablecontactstrengthF2EditField_2Label.Text = 'Allowable contact strength [σF]2';
            app.AllowablecontactstrengthF2EditField_2 = uieditfield(app.SofttoothsurfacePanel, 'numeric');
            app.ConclusionTextAreaLabel = uilabel(app.OutputTab);
            app.ConclusionTextAreaLabel.Text = 'Conclusion';
            app.ConclusionTextArea = uitextarea(app.OutputTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GearStength2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end